classdef joystick_delta_robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Label5        matlab.ui.control.Label
        Label4        matlab.ui.control.Label
        Label3        matlab.ui.control.Label
        Label2        matlab.ui.control.Label
        zSlider       matlab.ui.control.Slider
        zSliderLabel  matlab.ui.control.Label
        ySlider       matlab.ui.control.Slider
        ySliderLabel  matlab.ui.control.Label
        Label         matlab.ui.control.Label
        xSlider       matlab.ui.control.Slider
        xSliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R = .15;
        r = .1;
        alpha = [0, 2/3*pi, -2/3*pi];
        d = .1;
        h = .15;
        p = zeros(1, 3)';
        l_one,l_two,l_three = 0;
        Qal;

        tha, thb;
        dpos, bpos;
        a, b;
        joy % Joystick object
        
    end
    
    methods (Access = private)
        
        function calcAngles(app)
           for i=1:3
                l =  norm(-app.a(:,i) + app.b(:,i) + app.p); % Length of the linkage
                tmp = -2*app.d*(app.Qal(:,:,i)'*app.p + [app.r-app.R,0,0]');
                u = tmp(1);
                v = tmp(3);
                w = l^2 + app.d^2 - app.h^2;
                app.tha(i) = 2*atan2(-v+sqrt(u^2+v^2-w^2), w-u);
                app.thb(i) = 2*atan2(-v-sqrt(u^2+v^2-w^2), w-u);   
            end
            app.Label.Text = sprintf('app.tha(1) = %.4f\napp.tha(2) = %.4f\napp.tha(3) = %.4f', rad2deg(pi-app.tha(1)), rad2deg(pi-app.tha(2)), rad2deg(pi-app.tha(3)));

        end

        function plotBase(app)
            t = linspace(0,2*pi,40);
            base_h = 0*t;
            base_m = app.p(3)*ones(1, numel(t));
            plot3(app.UIAxes, app.R.*sin(t), app.R.*cos(t), base_h, 'LineWidth',2); hold(app.UIAxes, 'on');
            plot3(app.UIAxes, app.p(1)+app.r.*sin(t), app.p(2)+app.r.*cos(t), base_m, 'LineWidth',2); 
             set (app.UIAxes, "zdir", "reverse")
            set (app.UIAxes, "xdir", "reverse")
            set (app.UIAxes, "ydir", "reverse")
        end

        function plotGraph(app)
            for i = 1:3
                app.dpos(:,i) = app.a(:,i) + app.d*app.Qal(:,:,i)*[cos(pi-app.tha(i)), 0, sin(pi-app.tha(i))]';
                app.bpos(:,i) = app.p + app.b(:,i);
            end

            for i = 1:3
                plot3(app.UIAxes,  [app.a(1,i), app.dpos(1,i)], ...
                        [app.a(2,i), app.dpos(2,i)], ...
                        [app.a(3,i), app.dpos(3,i)], 'LineWidth',3);
                plot3(app.UIAxes,  [app.bpos(1,i), app.dpos(1,i)], ...
                        [app.bpos(2,i), app.dpos(2,i)], ...
                        [app.bpos(3,i), app.dpos(3,i)], 'LineWidth',3);
            end
            plot3(app.UIAxes, app.p(1), app.p(2), app.p(3), 'Marker','.' ,'MarkerSize', 3);
            app.tha;
        end

        function sendToESP32(app)
           
                  url = 'http://172.20.10.6/messageservice/async'; % Replace with your ESP32 IP address and endpoint
            % if(~isequal(app.l_one,app.tha(1)) && ~isequal(app.l_two,app.tha(2)) && ~isequal(app.l_three,app.tha(3)))
                 % Define the URL of the ESP32 server
     
              
            % Prepare the data to be sent as query parameters
             app.l_one = app.tha(1);
               app.l_two = app.tha(2);
               app.l_three = app.tha(3);
            data = struct('value1', rad2deg(pi-app.tha(1)), 'value2', rad2deg(pi-app.tha(2)), 'value3', rad2deg(pi-app.tha(3)));
        
            % Send the data as a JSON string
            options = weboptions('MediaType', 'application/json','Timeout',10);
             webwrite(url, jsonencode(data), options);
                 
             %PLOT END-EFFECTOR ONLY
                 plotBase(app);
            plotGraph(app); 
             % end
        end

        % Function to read joystick input
        function readJoystickInput(app)
            % Specify the axis numbers you want to read
            x = 1; % X axis
            y = 2; % Y axis
            z = 3; % Z axis

         % Loop to continuously read joystick input
    try
        while true
            % Read the status of the specified axes
            ax = axis(app.joy, x); % Read the x axis of the controller
            ay = axis(app.joy, y); % Read the y axis of the controller
            az = axis(app.joy, z); % Read the z axis of the controller

            % Update the labels with the axis values
            app.Label2.Text = sprintf('X Axis: %.3f', ax / 10 );
            app.Label3.Text = sprintf('Y Axis: %.3f', ay / 10);
            app.Label4.Text = sprintf('Z Axis: %.3f', az / 10);
            if ((ax)/10.00 ~= app.xSlider.Value || (ay)/10.00 ~= app.ySlider.Value || (az)/10.00 ~= app.zSlider.Value  )
           app.xSlider.Value = ((ax) /10.00) * -1;
            app.ySlider.Value = (ay) /10.00 ;
           app.zSlider.Value = (az) / 10.00 ;
            % disp(app.p);
            % Pause for a short duration to avoid flooding the UI
            pause(0.1);
            % if(app.p(1)>=0.1175 && app.p(1) < -0.12  ...
            %       && abs(app.p(2))>= 0.09 && app.p(2) < -0.09 ...
            %        && abs(app.p(3))>= 0.165 && app.p(3) <= 0.1175)
   

            if(app.zSlider.Value>= 0.089)
             
              zSliderValueChanging(app,az);
            else
                 app.zSlider.Value = 0.1;
            end
             xSliderValueChanging(app,ax);
              ySliderValueChanging(app,ay);
            end
                
              %SEND TO ESP32
               sendToESP32(app);
       % end
        end
    catch ME
        disp('Exiting joystick input loop.');
        disp(ME.message);
            app.Label5.Text = sprintf(ME.message);
    end

       
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create a joystick object for the first joystick
            app.joy = vrjoystick(1);

            % Display joystick information
            disp('Joystick connected:');
            disp(app.joy);
            for i=1:3
                app.Qal(:,:,i) = [...
                cos(app.alpha(i)) ,  -sin(app.alpha(i)), 0; 
                sin(app.alpha(i)) ,   cos(app.alpha(i)), 0; 
                0,0,1];
                app.a(:,i) =  app.Qal(:,:,i)*[app.R,0,0]';
                app.b(:,i) =  app.Qal(:,:,i)*[app.r,0,0]';
            end
              view(app.UIAxes, 3);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
              app.p(1)= 0.00;
            app.p(2) = 0.00;
             app.p(3) = 0.09;
             calcAngles(app);
           % plotBase(app);
           % plotGraph(app); 
            readJoystickInput(app);
             
        end

        % Button down function: UIAxes
        function init(app, event)


             % Start reading joystick input
          
           
            % sendToESP32(app);
        end

        % Callback function: xSlider, xSlider
        function xSliderValueChanging(app, event)
            cla(app.UIAxes);
          
             app.p(1) = app.xSlider.Value;
           
            calcAngles(app);
           
           % sendToESP32(app)
         %   plotBase(app);
%plotGraph(app); 
            
        end

        % Callback function: ySlider, ySlider
        function ySliderValueChanging(app, event)
            cla(app.UIAxes);
        
            app.p(2) = app.ySlider.Value;
            
            calcAngles(app);
          
           % sendToESP32(app);
       %   plotBase(app);
       %     plotGraph(app); 
         
        end

        % Callback function: zSlider, zSlider
        function zSliderValueChanging(app, event)
            cla(app.UIAxes);
           
            app.p(3) = app.zSlider.Value;
            calcAngles(app);
           
           %  sendToESP32(app);
     %   plotBase(app);
     %       plotGraph(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @init, true);
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Visible = 'off';
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-0.5 0.5];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.xSlider.Visible = 'off';
            app.Label = uilabel(app.UIFigure);
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Visible = 'off';
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-0.5 0.5];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.ySlider.Visible = 'off';
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-0.5 0.5];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.zSlider.Visible = 'off';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Label5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = joystick_delta_robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end