classdef delta_robot_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        HTML          matlab.ui.control.HTML
        zSlider       matlab.ui.control.Slider
        zSliderLabel  matlab.ui.control.Label
        ySlider       matlab.ui.control.Slider
        ySliderLabel  matlab.ui.control.Label
        Label         matlab.ui.control.Label
        xSlider       matlab.ui.control.Slider
        xSliderLabel  matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R = .15;
        r = .1;
        alpha = [0, 2/3*pi, -2/3*pi];
        d = .1;
        h = .15;
        p= [0.00,0.04,0.16]';
        Qal;

        tha, thb;
        dpos, bpos;
        a, b;
    end
     
    methods (Access = private)
        
        function calcAngles(app)
            for i=1:3
                l =  norm(-app.a(:,i) + app.b(:,i) + app.p); % Length of the linkage
                tmp = -2*app.d*(app.Qal(:,:,i)'*app.p + [app.r-app.R,0,0]');
                u = tmp(1);
                v = tmp(3);
                w = l^2 + app.d^2 - app.h^2;
                app.tha(i) = 2*atan2(-v+sqrt(u^2+v^2-w^2), w-u);
                app.thb(i) = 2*atan2(-v-sqrt(u^2+v^2-w^2), w-u);   
            end

            app.Label.Text = sprintf('app.tha(1) = %.4f\napp.tha(2) = %.4f\napp.tha(3) = %.4f', rad2deg(pi-app.tha(1)), rad2deg(pi-app.tha(2)), rad2deg(pi-app.tha(3)));

        end

        function plotBase(app)
            t = linspace(0,2*pi,40);
            base_h = 0*t;
            base_m = app.p(3)*ones(1, numel(t));
            plot3(app.UIAxes, app.R.*sin(t), app.R.*cos(t), base_h, 'LineWidth',2); hold(app.UIAxes, 'on');
            plot3(app.UIAxes, app.p(1)+app.r.*sin(t), app.p(2)+app.r.*cos(t), base_m, 'LineWidth',2); 
             set (app.UIAxes, "zdir", "reverse")
            set (app.UIAxes, "xdir", "reverse")
            set (app.UIAxes, "ydir", "reverse")
        end

        function plotGraph(app)
            for i = 1:3
                app.dpos(:,i) = app.a(:,i) + app.d*app.Qal(:,:,i)*[cos(pi-app.tha(i)), 0, sin(pi-app.tha(i))]';
                app.bpos(:,i) = app.p + app.b(:,i);
            end

            for i = 1:3
                plot3(app.UIAxes,  [app.a(1,i), app.dpos(1,i)], ...
                        [app.a(2,i), app.dpos(2,i)], ...
                        [app.a(3,i), app.dpos(3,i)], 'LineWidth',3);
                plot3(app.UIAxes,  [app.bpos(1,i), app.dpos(1,i)], ...
                        [app.bpos(2,i), app.dpos(2,i)], ...
                        [app.bpos(3,i), app.dpos(3,i)], 'LineWidth',3);
            end
            plot3(app.UIAxes, app.p(1), app.p(2), app.p(3), 'Marker','.' ,'MarkerSize', 3);
            app.tha;
        end

        function sendToESP32(app)
            % Define the URL of the ESP32 server
            url = 'http://172.20.10.3/messageservice/async'; % Replace with your ESP32 IP address and endpoint

            % Prepare the data to be sent as query parameters
            data = struct('value1', rad2deg(pi-app.tha(1)), 'value2', rad2deg(pi-app.tha(2)), 'value3', rad2deg(pi-app.tha(3)));

            % Send the data as a JSON string
            options = weboptions('MediaType', 'application/json');
            webwrite(url, jsonencode(data), options);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

% Get handle to the HTML Component
htmlComponent = app.HTML;

% Define the HTML content
htmlContent = [
    '<!DOCTYPE html>', ...
    '<html>', ...
    '<head>', ...
    '    <title>Mousebot</title>', ...
    '    <meta name="viewport" content="connect-src ''self'' http://127.0.0.1:8128 http://localhost:8000 http://localhost:9988" http-equiv="Content-Security-Policy" >', ...
    '</head>', ...
    '<body style="position: fixed; font-family: ''Gill Sans'', ''Gill Sans MT'', Calibri, ''Trebuchet MS'', sans-serif;', ...
    'color: rgb(128, 128, 128); font-size: xx-large;">', ...
    '    <h1 style="text-align:center">MOUSEBOT</h1>', ...
    '    <p style="text-align: center;">', ...
    '        X: <span id="x_coordinate"></span>', ...
    '        Y: <span id="y_coordinate"></span>', ...
    '        Speed: <span id="speed"></span> %', ...
    '        Angle: <span id="angle"></span>', ...
    '    </p>', ...
    '    <canvas id="canvas" name="game"></canvas>', ...
    '    <!-- Reference to the external JavaScript file -->', ...
    '    <script type="text/javascript" src="joystick.js"></script>', ...
    '</body>', ...
    '</html>'
];

% Set the HTML content in the component
htmlComponent.HTMLSource = htmlContent;

        end

        % Button down function: UIAxes
        function init(app, event)
            view(app.UIAxes, 3);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            app.xSlider.Value = 0.00;
            app.ySlider.Value = 0.04;
            app.zSlider.Value = 0.16;
            
            for i=1:3
                app.Qal(:,:,i) = [...
                cos(app.alpha(i)) ,  -sin(app.alpha(i)), 0; 
                sin(app.alpha(i)) ,   cos(app.alpha(i)), 0; 
                0,0,1];
                app.a(:,i) =  app.Qal(:,:,i)*[app.R,0,0]';
                app.b(:,i) =  app.Qal(:,:,i)*[app.r,0,0]';
            end
            
            calcAngles(app);
            plotBase(app);
            plotGraph(app);
            sendToESP32(app);
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(1) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app);
            sendToESP32(app);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(2) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app); 
            sendToESP32(app);
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(3) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app);  
            sendToESP32(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @init, true);
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-0.5 0.5];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-0.5 0.5];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-0.5 0.5];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.HTML = uihtml(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = delta_robot_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end