classdef delta_robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label3            matlab.ui.control.Label
        Label2            matlab.ui.control.Label
        VerticalButton    matlab.ui.control.Button
        HorizontalButton  matlab.ui.control.Button
        zSlider           matlab.ui.control.Slider
        zSliderLabel      matlab.ui.control.Label
        ySlider           matlab.ui.control.Slider
        ySliderLabel      matlab.ui.control.Label
        Label             matlab.ui.control.Label
        xSlider           matlab.ui.control.Slider
        xSliderLabel      matlab.ui.control.Label
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        R = .65;
        r = .52;
        alpha = [0, 2/3*pi, -2/3*pi];
        d = 1.25;
        h = 4.80;
        p= [0.00,0.00,5]';
        % p= [0.00,0.00,0.16]'
        Qal;

        tha, thb;
        dpos, bpos;
        a, b;
    end
     
    methods (Access = private)
        
        function calcAngles(app)
             for i=1:3
                app.Qal(:,:,i) = [...
                cos(app.alpha(i)) ,  -sin(app.alpha(i)), 0; 
                sin(app.alpha(i)) ,   cos(app.alpha(i)), 0; 
                0,0,1];
                app.a(:,i) =  app.Qal(:,:,i)*[app.R,0,0]';
                app.b(:,i) =  app.Qal(:,:,i)*[app.r,0,0]';
            end
            for i=1:3
                l =  norm(-app.a(:,i) + app.b(:,i) + app.p); % Length of the linkage
                disp(l);
                tmp = -2*app.d*(app.Qal(:,:,i)'*app.p + [app.r-app.R,0,0]');
                u = tmp(1);
                v = tmp(3);
                w = l^2 + app.d^2 - app.h^2;
                app.tha(i) = 2*atan2(-v+sqrt(u^2+v^2-w^2), w-u);
                app.thb(i) = 2*atan2(-v-sqrt(u^2+v^2-w^2), w-u);   
            end

            app.Label.Text = sprintf('app.tha(1) = %.4f\napp.tha(2) = %.4f\napp.tha(3) = %.4f', ...
                rad2deg(pi-app.tha(1)), rad2deg(pi-app.tha(2)), rad2deg(pi-app.tha(3)));

        end

        function plotGraph(app)
             t = linspace(0,2*pi,40);
            base_h = 0*t;
            base_m = app.p(3)*ones(1, numel(t));
            plot3(app.UIAxes, app.R.*sin(t), app.R.*cos(t), base_h, 'LineWidth',2); hold(app.UIAxes, 'on');
            plot3(app.UIAxes, app.p(1)+app.r.*sin(t), app.p(2)+app.r.*cos(t), base_m, 'LineWidth',2); 
            for i = 1:3
                app.dpos(:,i) = app.a(:,i) + app.d*app.Qal(:,:,i)*[cos(180-app.tha(i)), 0, sin(180-app.tha(i))]';
                app.bpos(:,i) = app.p + app.b(:,i);
            end

            % Initialize the lengths of the strings
            lengths_a_d = zeros(1, 3);
            lengths_b_d = zeros(1, 3);

            for i = 1:3

                % Calculate the length of each string from a to dpos
                lengths_a_d(i) = sqrt((app.a(1,i) - app.dpos(1,i))^2 + ...
                          (app.a(2,i) - app.dpos(2,i))^2 + ...
                          (app.a(3,i) - app.dpos(3,i))^2);
                      
                % Calculate the length of each string from bpos to dpos
                lengths_b_d(i) = sqrt((app.bpos(1,i) - app.dpos(1,i))^2 + ...
                          (app.bpos(2,i) - app.dpos(2,i))^2 + ...
                          (app.bpos(3,i) - app.dpos(3,i))^2);
                plot3(app.UIAxes,  [app.a(1,i), app.dpos(1,i)], ...
                        [app.a(2,i), app.dpos(2,i)], ...
                        [app.a(3,i), app.dpos(3,i)], 'LineWidth',3);

                plot3(app.UIAxes,  [app.bpos(1,i), app.dpos(1,i)], ...
                        [app.bpos(2,i), app.dpos(2,i)], ...
                        [app.bpos(3,i), app.dpos(3,i)], 'LineWidth',3);
            end
            app.Label2.Text = sprintf('%.4f\n%.4f\n%.4f', lengths_b_d(1), lengths_b_d(2), lengths_b_d(3));
            app.Label3.Text = sprintf('%.4f\n%.4f\n%.4f', lengths_a_d(1), lengths_a_d(2), lengths_a_d(3));

            plot3(app.UIAxes, app.p(1), app.p(2), app.p(3), 'Marker','.' ,'MarkerSize', 3);
            app.tha;
        end

        function sendToESP32(app)
            % Define the URL of the ESP32 server
            url = 'http://172.20.10.6/messageservice/async'; % Replace with your ESP32 IP address and endpoint
            % url = 'http://192.168.50.138/messageservice/async';

            % Prepare the data to be sent as query parameters
            data = struct('value1', rad2deg(pi-app.tha(1)), 'value2', rad2deg(pi-app.tha(2)), 'value3', rad2deg(pi-app.tha(3)));

            % Send the data as a JSON string
            options = weboptions('MediaType', 'application/json', 'Timeout', 10); % Increase timeout to 10 seconds
            webwrite(url, jsonencode(data), options);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            view(app.UIAxes, 3);
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            app.xSlider.Value = 0.00;
            app.ySlider.Value = 0.00;
            app.zSlider.Value = 0.16;

            % Set fixed axis limits
            xlim(app.UIAxes, [-10, 10]);
            ylim(app.UIAxes, [-10, 10]);
            zlim(app.UIAxes, [-5, 10]);
       
            
            calcAngles(app);
            plotGraph(app);
            % sendToESP32(app);

        end

        % Button down function: UIAxes
        function init(app, event)
            % view(app.UIAxes, 3);
            % hold(app.UIAxes, 'on');
            % grid(app.UIAxes, 'on');
            % app.xSlider.Value = 0.00;
            % app.ySlider.Value = 0.04;
            % app.zSlider.Value = 0.16;
            % 
            % for i=1:3
            %     app.Qal(:,:,i) = [...
            %     cos(app.alpha(i)) ,  -sin(app.alpha(i)), 0; 
            %     sin(app.alpha(i)) ,   cos(app.alpha(i)), 0; 
            %     0,0,1];
            %     app.a(:,i) =  app.Qal(:,:,i)*[app.R,0,0]';
            %     app.b(:,i) =  app.Qal(:,:,i)*[app.r,0,0]';
            % end
            % 
            % calcAngles(app);
            % plotBase(app);
            % plotGraph(app);
            % sendToESP32(app);
        end

        % Value changing function: xSlider
        function xSliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(1) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app);
            % sendToESP32(app);
        end

        % Value changing function: ySlider
        function ySliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(2) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app); 
            % sendToESP32(app);
        end

        % Value changing function: zSlider
        function zSliderValueChanging(app, event)
            cla(app.UIAxes);
            app.p(3) = event.Value;
            calcAngles(app);
            plotBase(app);
            plotGraph(app);  
            % sendToESP32(app);
        end

        % Button pushed function: HorizontalButton
        function HorizontalButtonPushed(app, event)
            % Set the parameters for the circular motion
            radius = 1;  % Radius of the circle (half of the diameter)
            centerX = 0.0;  % X coordinate of the center
            centerY = 0.0;  % Y coordinate of the center
            numSteps = 100;  % Number of steps to complete the circle
            zValue = 5;   % Z coordinate remains constant

            % Loop to update p in a circular path
            for i = 1:numSteps
                % Calculate the angle for this step
                theta = 2 * pi * i / numSteps;

                % Update the position of p
                app.p(1) = centerX + radius * cos(theta);
                app.p(2) = centerY + radius * sin(theta);
                app.p(3) = zValue;

                % Clear the axes and redraw the plot
                cla(app.UIAxes);
                calcAngles(app);
                plotGraph(app);

                % Send data to ESP32 (if needed)
                % sendToESP32(app);

                % Pause for a short time to create the animation effect
                pause(0.1);  % Adjust the pause duration for desired speed
            end
        end

        % Button pushed function: VerticalButton
        function VerticalButtonPushed(app, event)
            % Set the parameters for the vertical circular motion
            radius = 1;  % Radius of the circle (half of the diameter)
            centerY = 0.0;  % Y coordinate of the center
            centerZ = 5; % Z coordinate of the center (same as original z-value)
            numSteps = 100;  % Number of steps to complete the circle
            xValue = 0.0;    % X coordinate remains constant

            % Loop to update p in a vertical circular path
            for i = 1:numSteps
                % Calculate the angle for this step
                theta = 2 * pi * i / numSteps;
        
                % Update the position of p
                app.p(1) = xValue;
                app.p(2) = centerY + radius * cos(theta);
                app.p(3) = centerZ + radius * sin(theta);
        
                % Clear the axes and redraw the plot
                cla(app.UIAxes);
                calcAngles(app);
                plotGraph(app);
        
                % Send data to ESP32 (if needed)
                % sendToESP32(app);
        
                % Pause for a short time to create the animation effect
                pause(0.1);  % Adjust the pause duration for desired speed
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @init, true);
            app.xSliderLabel = uilabel(app.UIFigure);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.UIFigure);
            app.xSlider.Limits = [-0.5 0.5];
            app.xSlider.ValueChangingFcn = createCallbackFcn(app, @xSliderValueChanging, true);
            app.Label = uilabel(app.UIFigure);
            app.ySliderLabel = uilabel(app.UIFigure);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.UIFigure);
            app.ySlider.Limits = [-0.5 0.5];
            app.ySlider.ValueChangingFcn = createCallbackFcn(app, @ySliderValueChanging, true);
            app.zSliderLabel = uilabel(app.UIFigure);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.Limits = [-0.5 0.5];
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderValueChanging, true);
            app.HorizontalButton = uibutton(app.UIFigure, 'push');
            app.HorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalButtonPushed, true);
            app.HorizontalButton.Text = 'Horizontal';
            app.VerticalButton = uibutton(app.UIFigure, 'push');
            app.VerticalButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalButtonPushed, true);
            app.VerticalButton.Text = 'Vertical';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = delta_robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end